classdef pr2gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RButton               matlab.ui.control.StateButton
        RButton_2             matlab.ui.control.StateButton
        PButton_2             matlab.ui.control.StateButton
        PButton               matlab.ui.control.StateButton
        YButton_2             matlab.ui.control.StateButton
        YButton               matlab.ui.control.StateButton
        XButton               matlab.ui.control.StateButton
        XButton_2             matlab.ui.control.StateButton
        YButton_4             matlab.ui.control.StateButton
        YButton_3             matlab.ui.control.StateButton
        ZButton               matlab.ui.control.StateButton
        ZButton_2             matlab.ui.control.StateButton
        LeftLamp              matlab.ui.control.Lamp
        XYZLabel              matlab.ui.control.Label
        RPYLabel              matlab.ui.control.Label
        PR2ControlPanelLabel  matlab.ui.control.Label
        ESTOPButton           matlab.ui.control.Button
        Link7Slider           matlab.ui.control.Slider
        Link7SliderLabel      matlab.ui.control.Label
        Link6Slider           matlab.ui.control.Slider
        Link6SliderLabel      matlab.ui.control.Label
        Link5Slider           matlab.ui.control.Slider
        Link5SliderLabel      matlab.ui.control.Label
        Link4Slider           matlab.ui.control.Slider
        Link4SliderLabel      matlab.ui.control.Label
        Link3Slider           matlab.ui.control.Slider
        Link3SliderLabel      matlab.ui.control.Label
        Link2Slider           matlab.ui.control.Slider
        Link2SliderLabel      matlab.ui.control.Label
        Link1Slider           matlab.ui.control.Slider
        Link1Label            matlab.ui.control.Label
        L7Spinner             matlab.ui.control.Spinner
        L7SpinnerLabel        matlab.ui.control.Label
        L6Spinner             matlab.ui.control.Spinner
        L6SpinnerLabel        matlab.ui.control.Label
        L5Spinner             matlab.ui.control.Spinner
        L5SpinnerLabel        matlab.ui.control.Label
        L4Spinner             matlab.ui.control.Spinner
        L4SpinnerLabel        matlab.ui.control.Label
        L3Spinner             matlab.ui.control.Spinner
        L3SpinnerLabel        matlab.ui.control.Label
        L2Spinner             matlab.ui.control.Spinner
        L2SpinnerLabel        matlab.ui.control.Label
        L1Spinner             matlab.ui.control.Spinner
        L1SpinnerLabel        matlab.ui.control.Label
        RightLamp             matlab.ui.control.Lamp
        Switch                matlab.ui.control.Switch
        SwitchLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)

        % ROBOT
        PR2Left   % PR2 Left Arm model
        PR2Right  % PR2 Right Arm model
        PR2LeftQlim
        PR2RightQlim
        QPR2Left
        QPR2Right
        controlArm = 'Left'; % Current control arm
        numJoints = 7;       % Number of joints in the arm
        LinkSliders          % Array of Link sliders
        LinkSpinners         % Array of Link spinners
        armValue

        %MOTION
        xplus
        xmin
        yplus
        ymin
        zplus
        zmin
        rplus
        rmin
        pplus
        pmin
        ywplus
        ywmin

        %Jogging
        vx
        vy
        vz
        wx
        wy
        wz
        Kv
        Kw
        vectorm

        %Timer
        pr2Timer
    
    end
    
    methods (Access = private)
         
        function velocityCalc(app)
            app.vx = app.Kv*(app.xplus - app.xmin);
            app.vy = app.Kv*(app.yplus - app.ymin);
            app.vz = app.Kv*(app.zplus - app.zmin);
            app.wx = app.Kw*(app.rplus - app.rmin);
            app.wy = app.Kw*(app.pplus - app.pmin);
            app.wz = app.Kw*(app.ywplus - app.ywmin);
            app.vectorm = [app.vx app.vy app.vz app.wx app.wy app.wz];
        end

        function updateApp(app)
            app.velocityCalc
        end
%{
        function updateQvalues(app)
            if app.armValue == 0
                
                app.QPR2Left = app.PR2Left.model.getpos;
                app.Link1Slider.Limits = rad2deg(app.PR2LeftQlim(1,:));
                app.Link2Slider.Limits = rad2deg(app.PR2LeftQlim(2,:));
                app.Link3Slider.Limits = rad2deg(app.PR2LeftQlim(3,:));
                app.Link4Slider.Limits = rad2deg(app.PR2LeftQlim(4,:));
                app.Link5Slider.Limits = rad2deg(app.PR2LeftQlim(5,:));
                app.Link6Slider.Limits = rad2deg(app.PR2LeftQlim(6,:));
                app.Link7Slider.Limits = rad2deg(app.PR2LeftQlim(7,:));
                
                app.Link1Slider.Value = rad2deg(app.QPR2Left(1));
                app.Link2Slider.Value = rad2deg(app.QPR2Left(2));
                app.Link3Slider.Value = rad2deg(app.QPR2Left(3));
                app.Link4Slider.Value = rad2deg(app.QPR2Left(4));
                app.Link5Slider.Value = rad2deg(app.QPR2Left(5));
                app.Link6Slider.Value = rad2deg(app.QPR2Left(6));
                app.Link7Slider.Value = rad2deg(app.QPR2Left(7));


            elseif app.armValue == 1
                
                app.QPR2Left = app.PR2Left.model.getpos;
                app.Link1Slider.Limits = rad2deg(app.PR2RightQlim(1,:));
                app.Link2Slider.Limits = rad2deg(app.PR2RightQlim(2,:));
                app.Link3Slider.Limits = rad2deg(app.PR2RightQlim(3,:));
                app.Link4Slider.Limits = rad2deg(app.PR2RightQlim(4,:));
                app.Link5Slider.Limits = rad2deg(app.PR2RightQlim(5,:));
                app.Link6Slider.Limits = rad2deg(app.PR2RightQlim(6,:));
                app.Link7Slider.Limits = rad2deg(app.PR2RightQlim(7,:));
                
                app.Link1Slider.Value = rad2deg(app.QPR2Right(1));
                app.Link2Slider.Value = rad2deg(app.QPR2Right(2));
                app.Link3Slider.Value = rad2deg(app.QPR2Right(3));
                app.Link4Slider.Value = rad2deg(app.QPR2Right(4));
                app.Link5Slider.Value = rad2deg(app.QPR2Right(5));
                app.Link6Slider.Value = rad2deg(app.QPR2Right(6));
                app.Link7Slider.Value = rad2deg(app.QPR2Right(7));
            
            end
        end
%}
    end

    %%

        methods (Access = public)
        
        function readButtons(app)

            pr2Control(1) = app.xplus;
            pr2Control(2) = app.xmin;
            pr2Control(3) = app.yplus;
            pr2Control(4) = app.ymin;
            pr2Control(5) = app.zplus;
            pr2Control(6) = app.zmin;
            pr2Control(7) = app.rplus;
            pr2Control(8) = app.rmin;
            pr2Control(9) = app.pplus;
            pr2Control(10) = app.pmin;
            pr2Control(11) = app.ywplus;
            pr2Control(12) = app.ywmin;
            
            % when there is a change -> update calculations and animate
            if pr2Control(1)||pr2Control(2)||pr2Control(3)||pr2Control(4)||pr2Control(5)||pr2Control(6)
                app.updateApp
            elseif pr2Control(7)||pr2Control(8)||pr2Control(9)||pr2Control(10)||pr2Control(11)||pr2Control(12)
                app.updateApp
            end
                   
            if app.armValue == 0
                app.Link1Slider.Value = rad2deg(app.QPR2Left(1));
                app.Link2Slider.Value = rad2deg(app.QPR2Left(2));
                app.Link3Slider.Value = rad2deg(app.QPR2Left(3));
                app.Link4Slider.Value = rad2deg(app.QPR2Left(4));
                app.Link5Slider.Value = rad2deg(app.QPR2Left(5));
                app.Link6Slider.Value = rad2deg(app.QPR2Left(6));
                app.Link7Slider.Value = rad2deg(app.QPR2Left(7));
            elseif app.armValue == 1
                app.Link1Slider.Value = rad2deg(app.QPR2Right(1));
                app.Link2Slider.Value = rad2deg(app.QPR2Right(2));
                app.Link3Slider.Value = rad2deg(app.QPR2Right(3));
                app.Link4Slider.Value = rad2deg(app.QPR2Right(4));
                app.Link5Slider.Value = rad2deg(app.QPR2Right(5));
                app.Link6Slider.Value = rad2deg(app.QPR2Right(6));
                app.Link7Slider.Value = rad2deg(app.QPR2Right(7));
            end
        end
        
        function updateQvalues(app)
            if app.armValue == 0
                
                app.QPR2Left = app.PR2Left.model.getpos;
                app.Link1Slider.Limits = rad2deg(app.PR2LeftQlim(1,:));
                app.Link2Slider.Limits = rad2deg(app.PR2LeftQlim(2,:));
                app.Link3Slider.Limits = rad2deg(app.PR2LeftQlim(3,:));
                app.Link4Slider.Limits = rad2deg(app.PR2LeftQlim(4,:));
                app.Link5Slider.Limits = rad2deg(app.PR2LeftQlim(5,:));
                app.Link6Slider.Limits = rad2deg(app.PR2LeftQlim(6,:));
                app.Link7Slider.Limits = rad2deg(app.PR2LeftQlim(7,:));
                
                app.Link1Slider.Value = rad2deg(app.QPR2Left(1));
                app.Link2Slider.Value = rad2deg(app.QPR2Left(2));
                app.Link3Slider.Value = rad2deg(app.QPR2Left(3));
                app.Link4Slider.Value = rad2deg(app.QPR2Left(4));
                app.Link5Slider.Value = rad2deg(app.QPR2Left(5));
                app.Link6Slider.Value = rad2deg(app.QPR2Left(6));
                app.Link7Slider.Value = rad2deg(app.QPR2Left(7));


            elseif app.armValue == 1
                
                app.QPR2Left = app.PR2Left.model.getpos;
                app.Link1Slider.Limits = rad2deg(app.PR2RightQlim(1,:));
                app.Link2Slider.Limits = rad2deg(app.PR2RightQlim(2,:));
                app.Link3Slider.Limits = rad2deg(app.PR2RightQlim(3,:));
                app.Link4Slider.Limits = rad2deg(app.PR2RightQlim(4,:));
                app.Link5Slider.Limits = rad2deg(app.PR2RightQlim(5,:));
                app.Link6Slider.Limits = rad2deg(app.PR2RightQlim(6,:));
                app.Link7Slider.Limits = rad2deg(app.PR2RightQlim(7,:));
                
                app.Link1Slider.Value = rad2deg(app.QPR2Right(1));
                app.Link2Slider.Value = rad2deg(app.QPR2Right(2));
                app.Link3Slider.Value = rad2deg(app.QPR2Right(3));
                app.Link4Slider.Value = rad2deg(app.QPR2Right(4));
                app.Link5Slider.Value = rad2deg(app.QPR2Right(5));
                app.Link6Slider.Value = rad2deg(app.QPR2Right(6));
                app.Link7Slider.Value = rad2deg(app.QPR2Right(7));
            
            end
        end
        
        function getRobot(app, PR2Left, PR2Right)
            
            app.PR2Left = PR2Left;
            app.PR2LeftQlim = app.PR2Left.model.qlim;
            app.QPR2Left = app.PR2Left.model.getpos;

            app.PR2Right = PR2Right;
            app.PR2RightQlim = app.PR2Right.model.qlim;
            app.QPR2Right = app.PR2Right.model.getpos;
            
            app.updateApp;
            app.updateQvalues;
        end
        
        function [qmatrix1,qmatrix2] = animateQvalues(app)
            app.readButtons;
            qmatrix1 = app.QPR2Left;
            qmatrix2 = app.QPR2Right;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xplus = 0;
            app.xmin = 0;
            app.yplus = 0;
            app.ymin = 0;
            app.zplus = 0;
            app.zmin = 0;
            app.rplus = 0;
            app.rmin = 0;
            app.pplus = 0;
            app.pmin = 0;
            app.ywplus = 0;
            app.ywmin = 0;
            
            app.vx = 0;
            app.vy = 0;
            app.vz = 0;
            app.wx = 0;
            app.wy = 0;
            app.wz = 0;
            
            app.Kv = 0.5;
            app.Kw = 1;
            
            app.armValue = 0;
            
            app.pr2Timer = timer('Period',0.05,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            
            app.pr2Timer.TimerFcn = @(~,~)app.updateApp;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
             if strcmp(value, 'Right Arm')
                 app.armValue = 1;
                 app.RightLamp.Color = 'g';
                 app.LeftLamp.Color = 'r';

             else
                app.armValue = 0;
                app.RightLamp.Color = 'r';
                app.LeftLamp.Color = 'g';
             end
        end

        % Value changed function: RButton
        function RButtonValueChanged(app, event)
            app.rplus = app.RButton.Value;
            
        end

        % Value changed function: RButton_2
        function RButton_2ValueChanged(app, event)
            app.rmius = app.RButton_2.Value;
            
        end

        % Value changed function: PButton
        function PButtonValueChanged(app, event)
            app.pplus = app.PButton.Value;
            
        end

        % Value changed function: PButton_2
        function PButton_2ValueChanged(app, event)
            app.pmin = app.PButton_2.Value;
            
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            app.ywplus = app.YButton.Value;
            
        end

        % Value changed function: YButton_2
        function YButton_2ValueChanged(app, event)
            app.ywmin = app.YButton_2.Value;
            
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            app.xplus = app.XButton.Value;
            
        end

        % Value changed function: XButton_2
        function XButton_2ValueChanged(app, event)
            app.xmin = app.XButton_2.Value;
            
        end

        % Value changed function: YButton_3
        function YButton_3ValueChanged(app, event)
            app.yplus = app.YButton_3.Value;
            
        end

        % Value changed function: YButton_4
        function YButton_4ValueChanged(app, event)
            app.ymin = app.YButton_4.Value;
            
        end

        % Value changed function: ZButton
        function ZButtonValueChanged(app, event)
            app.zplus = app.ZButton.Value;
            
        end

        % Value changed function: ZButton_2
        function ZButton_2ValueChanged(app, event)
            app.zmin = app.ZButton_2.Value;
            
        end

        % Value changing function: Link1Slider
        function Link1SliderValueChanging(app, event)
             if app.armValue == 0
                app.QPR2Left(1) = deg2rad(event.Value);
            elseif app.armValue == 1
                app.QPR2Right(1) = deg2rad(event.Value);
             end
        end

        % Value changing function: Link2Slider
        function Link2SliderValueChanging(app, event)
             if app.armValue == 0
                app.QPR2Left(1) = deg2rad(event.Value);
            elseif app.armValue == 1
                app.QPR2Right(1) = deg2rad(event.Value);
             end
        end

        % Value changing function: Link3Slider
        function Link3SliderValueChanging(app, event)
             if app.armValue == 0
                app.QPR2Left(1) = deg2rad(event.Value);
            elseif app.armValue == 1
                app.QPR2Right(1) = deg2rad(event.Value);
             end
        end

        % Value changing function: Link4Slider
        function Link4SliderValueChanging(app, event)
             if app.armValue == 0
                app.QPR2Left(1) = deg2rad(event.Value);
            elseif app.armValue == 1
                app.QPR2Right(1) = deg2rad(event.Value);
             end
        end

        % Value changing function: Link5Slider
        function Link5SliderValueChanging(app, event)
             if app.armValue == 0
                app.QPR2Left(1) = deg2rad(event.Value);
            elseif app.armValue == 1
                app.QPR2Right(1) = deg2rad(event.Value);
             end
        end

        % Value changing function: Link6Slider
        function Link6SliderValueChanging(app, event)
             if app.armValue == 0
                app.QPR2Left(1) = deg2rad(event.Value);
            elseif app.armValue == 1
                app.QPR2Right(1) = deg2rad(event.Value);
             end
        end

        % Value changing function: Link7Slider
        function Link7SliderValueChanging(app, event)
             if app.armValue == 0
                app.QPR2Left(1) = deg2rad(event.Value);
            elseif app.armValue == 1
                app.QPR2Right(1) = deg2rad(event.Value);
             end
        end

        % Value changed function: Link1Slider
        function Link1SliderValueChanged(app, event)
            if app.armValue == 0
                app.QPR2Left(1) = deg2rad(app.Link1Slider.Value);
            elseif app.armValue == 1
                app.QPR2Right(1) = deg2rad(app.Link1Slider.Value);
            end
        end

        % Value changed function: Link2Slider
        function Link2SliderValueChanged(app, event)
            if app.armValue == 0
                app.QPR2Left(2) = deg2rad(app.Link1Slider.Value);
            elseif app.armValue == 1
                app.QPR2Right(2) = deg2rad(app.Link1Slider.Value);
            end
        end

        % Value changed function: Link3Slider
        function Link3SliderValueChanged(app, event)
            if app.armValue == 0
                app.QPR2Left(3) = deg2rad(app.Link1Slider.Value);
            elseif app.armValue == 1
                app.QPR2Right(3) = deg2rad(app.Link1Slider.Value);
            end
        end

        % Value changed function: Link4Slider
        function Link4SliderValueChanged(app, event)
            if app.armValue == 0
                app.QPR2Left(4) = deg2rad(app.Link1Slider.Value);
            elseif app.armValue == 1
                app.QPR2Right(4) = deg2rad(app.Link1Slider.Value);
            end
        end

        % Value changed function: Link5Slider
        function Link5SliderValueChanged(app, event)
            if app.armValue == 0
                app.QPR2Left(5) = deg2rad(app.Link1Slider.Value);
            elseif app.armValue == 1
                app.QPR2Right(5) = deg2rad(app.Link1Slider.Value);
            end
        end

        % Value changed function: Link6Slider
        function Link6SliderValueChanged(app, event)
            if app.armValue == 0
                app.QPR2Left(6) = deg2rad(app.Link1Slider.Value);
            elseif app.armValue == 1
                app.QPR2Right(6) = deg2rad(app.Link1Slider.Value);
            end
        end

        % Value changed function: Link7Slider
        function Link7SliderValueChanged(app, event)
            if app.armValue == 0
                app.QPR2Left(7) = deg2rad(app.Link1Slider.Value);
            elseif app.armValue == 1
                app.QPR2Right(7) = deg2rad(app.Link1Slider.Value);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Left Arm', 'Right Arm'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Left Arm';
            app.RightLamp = uilamp(app.UIFigure);
            app.L1SpinnerLabel = uilabel(app.UIFigure);
            app.L1SpinnerLabel.Text = 'L1';
            app.L1Spinner = uispinner(app.UIFigure);
            app.L2SpinnerLabel = uilabel(app.UIFigure);
            app.L2SpinnerLabel.Text = 'L2';
            app.L2Spinner = uispinner(app.UIFigure);
            app.L3SpinnerLabel = uilabel(app.UIFigure);
            app.L3SpinnerLabel.Text = 'L3';
            app.L3Spinner = uispinner(app.UIFigure);
            app.L4SpinnerLabel = uilabel(app.UIFigure);
            app.L4SpinnerLabel.Text = 'L4';
            app.L4Spinner = uispinner(app.UIFigure);
            app.L5SpinnerLabel = uilabel(app.UIFigure);
            app.L5SpinnerLabel.Text = 'L5';
            app.L5Spinner = uispinner(app.UIFigure);
            app.L6SpinnerLabel = uilabel(app.UIFigure);
            app.L6SpinnerLabel.Text = 'L6';
            app.L6Spinner = uispinner(app.UIFigure);
            app.L7SpinnerLabel = uilabel(app.UIFigure);
            app.L7SpinnerLabel.Text = 'L7';
            app.L7Spinner = uispinner(app.UIFigure);
            app.Link1Label = uilabel(app.UIFigure);
            app.Link1Label.Text = 'Link 1';
            app.Link1Slider = uislider(app.UIFigure);
            app.Link1Slider.ValueChangedFcn = createCallbackFcn(app, @Link1SliderValueChanged, true);
            app.Link1Slider.ValueChangingFcn = createCallbackFcn(app, @Link1SliderValueChanging, true);
            app.Link2SliderLabel = uilabel(app.UIFigure);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.UIFigure);
            app.Link2Slider.ValueChangedFcn = createCallbackFcn(app, @Link2SliderValueChanged, true);
            app.Link2Slider.ValueChangingFcn = createCallbackFcn(app, @Link2SliderValueChanging, true);
            app.Link3SliderLabel = uilabel(app.UIFigure);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.UIFigure);
            app.Link3Slider.ValueChangedFcn = createCallbackFcn(app, @Link3SliderValueChanged, true);
            app.Link3Slider.ValueChangingFcn = createCallbackFcn(app, @Link3SliderValueChanging, true);
            app.Link4SliderLabel = uilabel(app.UIFigure);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.UIFigure);
            app.Link4Slider.ValueChangedFcn = createCallbackFcn(app, @Link4SliderValueChanged, true);
            app.Link4Slider.ValueChangingFcn = createCallbackFcn(app, @Link4SliderValueChanging, true);
            app.Link5SliderLabel = uilabel(app.UIFigure);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5Slider = uislider(app.UIFigure);
            app.Link5Slider.ValueChangedFcn = createCallbackFcn(app, @Link5SliderValueChanged, true);
            app.Link5Slider.ValueChangingFcn = createCallbackFcn(app, @Link5SliderValueChanging, true);
            app.Link6SliderLabel = uilabel(app.UIFigure);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider = uislider(app.UIFigure);
            app.Link6Slider.ValueChangedFcn = createCallbackFcn(app, @Link6SliderValueChanged, true);
            app.Link6Slider.ValueChangingFcn = createCallbackFcn(app, @Link6SliderValueChanging, true);
            app.Link7SliderLabel = uilabel(app.UIFigure);
            app.Link7SliderLabel.Text = 'Link 7';
            app.Link7Slider = uislider(app.UIFigure);
            app.Link7Slider.ValueChangedFcn = createCallbackFcn(app, @Link7SliderValueChanged, true);
            app.Link7Slider.ValueChangingFcn = createCallbackFcn(app, @Link7SliderValueChanging, true);
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.Text = 'E-STOP';
            app.PR2ControlPanelLabel = uilabel(app.UIFigure);
            app.PR2ControlPanelLabel.Text = 'PR2 Control Panel';
            app.RPYLabel = uilabel(app.UIFigure);
            app.RPYLabel.Text = 'RPY';
            app.XYZLabel = uilabel(app.UIFigure);
            app.XYZLabel.Text = 'XYZ';
            app.LeftLamp = uilamp(app.UIFigure);
            app.ZButton_2 = uibutton(app.UIFigure, 'state');
            app.ZButton_2.ValueChangedFcn = createCallbackFcn(app, @ZButton_2ValueChanged, true);
            app.ZButton_2.Text = 'Z-';
            app.ZButton = uibutton(app.UIFigure, 'state');
            app.ZButton.ValueChangedFcn = createCallbackFcn(app, @ZButtonValueChanged, true);
            app.ZButton.Text = 'Z+';
            app.YButton_3 = uibutton(app.UIFigure, 'state');
            app.YButton_3.ValueChangedFcn = createCallbackFcn(app, @YButton_3ValueChanged, true);
            app.YButton_3.Text = 'Y+';
            app.YButton_4 = uibutton(app.UIFigure, 'state');
            app.YButton_4.ValueChangedFcn = createCallbackFcn(app, @YButton_4ValueChanged, true);
            app.YButton_4.Text = 'Y-';
            app.XButton_2 = uibutton(app.UIFigure, 'state');
            app.XButton_2.ValueChangedFcn = createCallbackFcn(app, @XButton_2ValueChanged, true);
            app.XButton_2.Text = 'X-';
            app.XButton = uibutton(app.UIFigure, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = 'X+';
            app.YButton = uibutton(app.UIFigure, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = 'Y+';
            app.YButton_2 = uibutton(app.UIFigure, 'state');
            app.YButton_2.ValueChangedFcn = createCallbackFcn(app, @YButton_2ValueChanged, true);
            app.YButton_2.Text = 'Y-';
            app.PButton = uibutton(app.UIFigure, 'state');
            app.PButton.ValueChangedFcn = createCallbackFcn(app, @PButtonValueChanged, true);
            app.PButton.Text = 'P+';
            app.PButton_2 = uibutton(app.UIFigure, 'state');
            app.PButton_2.ValueChangedFcn = createCallbackFcn(app, @PButton_2ValueChanged, true);
            app.PButton_2.Text = 'P-';
            app.RButton_2 = uibutton(app.UIFigure, 'state');
            app.RButton_2.ValueChangedFcn = createCallbackFcn(app, @RButton_2ValueChanged, true);
            app.RButton_2.Text = 'R-';
            app.RButton = uibutton(app.UIFigure, 'state');
            app.RButton.ValueChangedFcn = createCallbackFcn(app, @RButtonValueChanged, true);
            app.RButton.Text = 'R+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pr2gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end